classdef TsunamiApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        MaxHeightEditField            matlab.ui.control.EditField
        MaxHeightEditFieldLabel       matlab.ui.control.Label
        PredictedIntensityGauge       matlab.ui.control.LinearGauge
        PredictedIntensityGaugeLabel  matlab.ui.control.Label
        EarthquakeMagnitudeEditField  matlab.ui.control.EditField
        EarthquakeMagnitudeEditFieldLabel  matlab.ui.control.Label
        IidaMagnitudeEditField        matlab.ui.control.EditField
        IidaMagnitudeEditFieldLabel   matlab.ui.control.Label
        PredictButton                 matlab.ui.control.Button
        Image                         matlab.ui.control.Image
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PredictButton
        function PredictButtonPushed(app, event)
            load('Model.mat', 'Model')
            IidaMagnitude = str2double(app.IidaMagnitudeEditField.Value);
            EarthquakeMagnitude = str2double(app.EarthquakeMagnitudeEditField.Value);
            MaxHeight = str2double(app.MaxHeightEditField.Value);
            predictedIntensity = Model.predictFcn(table(IidaMagnitude,EarthquakeMagnitude,MaxHeight));
            app.PredictedIntensityGauge.Value = predictedIntensity;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'Tsunami_Image.jpg';
            app.PredictButton = uibutton(app.UIFigure, 'push');
            app.PredictButton.ButtonPushedFcn = createCallbackFcn(app, @PredictButtonPushed, true);
            app.PredictButton.Text = 'Predict';
            app.IidaMagnitudeEditFieldLabel = uilabel(app.UIFigure);
            app.IidaMagnitudeEditFieldLabel.Text = 'Iida Magnitude';
            app.IidaMagnitudeEditField = uieditfield(app.UIFigure, 'text');
            app.IidaMagnitudeEditField.InputType = 'digits';
            app.EarthquakeMagnitudeEditFieldLabel = uilabel(app.UIFigure);
            app.EarthquakeMagnitudeEditFieldLabel.Text = 'Earthquake Magnitude';
            app.EarthquakeMagnitudeEditField = uieditfield(app.UIFigure, 'text');
            app.EarthquakeMagnitudeEditField.InputType = 'digits';
            app.PredictedIntensityGaugeLabel = uilabel(app.UIFigure);
            app.PredictedIntensityGaugeLabel.Text = 'Predicted Intensity';
            app.PredictedIntensityGauge = uigauge(app.UIFigure, 'linear');
            app.PredictedIntensityGauge.Limits = [1 3];
            app.PredictedIntensityGauge.Value = 1;
            app.MaxHeightEditFieldLabel = uilabel(app.UIFigure);
            app.MaxHeightEditFieldLabel.Text = 'Max Height';
            app.MaxHeightEditField = uieditfield(app.UIFigure, 'text');
            app.MaxHeightEditField.InputType = 'digits';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TsunamiApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end